classdef basicAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        LabelNumericEditField3 matlab.ui.control.Label            % Blank c...
        NumericEditField3      matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelEditField         matlab.ui.control.Label            % Date
        EditField              matlab.ui.control.EditField       
        StateButton            matlab.ui.control.StateButton      % Start:)
        LabelEditField2        matlab.ui.control.Label            % Numbers...
        EditField2             matlab.ui.control.EditField       
        LabelNumericEditField2 matlab.ui.control.Label            % Y size
        NumericEditField2      matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField  matlab.ui.control.Label            % X size
        NumericEditField       matlab.ui.control.NumericEditField % [-Inf Inf]
        Label                  matlab.ui.control.Label            %  Basic ...
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % StateButton value changed function
        function StartButtonValueChanged(app)
            value = app.StateButton.Value;
            xSize=app.NumericEditField.Value;
            ySize=app.NumericEditField2.Value;
            blankCond=app.NumericEditField3.Value;
            numOfConds=app.EditField2.Value;
            numOfConds=str2double(strsplit(numOfConds,' '));
            date=app.EditField.Value;
            basicAnalysisGui(xSize,ySize,numOfConds,blankCond,date)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LabelNumericEditField3 = uilabel(app.UIFigure);
            app.LabelNumericEditField3.Text = 'Blank condition';
            app.NumericEditField3 = uieditfield(app.UIFigure, 'numeric');
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Date';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.StateButton = uibutton(app.UIFigure, 'state');
            app.StateButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged);
            app.StateButton.Text = 'Start:)';
            app.LabelEditField2 = uilabel(app.UIFigure);
            app.LabelEditField2.Text = 'Numbers of conds (blank not included, split by ''  '' )';
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'Y size';
            app.NumericEditField2 = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'X size';
            app.NumericEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ' Basic analysis Gui ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = basicAnalysis()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end